classdef matlabEapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        BaudRateEditFieldLabel      matlab.ui.control.Label
        BaudRateEditField           matlab.ui.control.NumericEditField
        ComPortEditFieldLabel       matlab.ui.control.Label
        ComPortEditField            matlab.ui.control.EditField
        ConnectButton               matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        AK1SliderLabel              matlab.ui.control.Label
        AK1Slider                   matlab.ui.control.Slider
        AK2SliderLabel              matlab.ui.control.Label
        AK2Slider                   matlab.ui.control.Slider
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        samplefromFSRsensorButton   matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        Samplefrequencys1EditFieldLabel  matlab.ui.control.Label
        Samplefrequencys1EditField  matlab.ui.control.NumericEditField
        OBSSamplefrequencyupdateitsvaluewhenpressingstopLabel  matlab.ui.control.Label
        CalibrateFSRsensorButton    matlab.ui.control.StateButton
        Switch                      matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        arduinoObj         % Serial object
        MemoryTimer        % Timer object
        PlotLine           % Line object
        P1                 % calibrated fsr sensor
        raaData            %
        calData            %
        calIndicator       % Signal, that the calibration has been done 
    end
    
    methods (Access = private) 
        function  MemoryTimerFcn(app,~,~)              
            write(app.arduinoObj,"F","char"); 
            data = 'b'; 
            i = 1;   
            while data ~= 'a'
                data = read(app.arduinoObj,  1, "char"); 
                if (data ~= 'a')
                    str(i) = data; 
                    i = i+1; 
                end
            end
            
            voltage = str2double(str)*5/1023;
            app.raaData = circshift(app.raaData,1);
            app.raaData(1) = voltage; 
            
            if app.calIndicator == true
                app.calData = circshift(app.calData,1);
                
                mass = polyval(app.P1, str2double(str)); 
                app.calData(1) = mass; 
            end
            
            plot(app.UIAxes,0:60,app.raaData,0:60,app.calData);
            
            % Update YData in plot           
%             voltage = str2double(str)*5/1023; 
%             ydata = app.PlotLine.YData;
%             ydata = circshift(ydata,1);
%             ydata(1) = voltage;
%             app.PlotLine.YData = ydata;
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Configure x- and y- axis
            app.UIAxes.XLim = [0 60];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 10];
            app.raaData = zeros(1,61); 
            app.calData = zeros(1,61); 
            app.calIndicator = false; 
            
            
            % Initial plot is all zeros
            %app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            plot(app.UIAxes,0:60,app.raaData,0:60,app.calData);

            
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Lamp.Color = "yellow";
            
            if ~isempty(instrfind) %check if any instrument are connected
                fclose(instrfind); %close com if any open 
                delete(instrfind); 
            end
            
            try
               app.arduinoObj = serialport(app.ComPortEditField.Value,app.BaudRateEditField.Value);
               stopSection = false;
            catch ME
                app.Lamp.Color = "red";
                app.ComPortEditField.FontColor = "red";
                mbox = msgbox('ComPort ERROR'); uiwait(mbox); 
                stopSection = true;  
            end
            
            if stopSection == false
                app.ComPortEditField.FontColor = "black";
                % Mirrors the arduino acknowledgement routine :
                data = 'b'; 
                
                % Read a 8bit precision character until 'a' is read
                while (data ~= 'a') 
                    data = read(app.arduinoObj,1,"char"); 
                end
                
                
                % Character read from arduino
                if (data == 'a')
                    % Writes on serialPort (in char) a letter a.
                    write(app.arduinoObj,"a","char")
                    app.Lamp.Color = "green";
                end 
            end
        end

        % Button pushed function: samplefromFSRsensorButton
        function samplefromFSRsensorButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.MemoryTimer.Running, 'off')
               start(app.MemoryTimer);
            end  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
            stop(app.MemoryTimer);
           
            app.MemoryTimer.Period = 1/app.Samplefrequencys1EditField.Value;


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            delete(app);
        end

        % Value changed function: AK1Slider
        function AK1SliderValueChanged(app, event)
%             write(app.arduinoObj,"Z","char");
            write(app.arduinoObj,fix(app.AK1Slider.Value),"uint8");
            write(app.arduinoObj,"a","char");
                   
            
        end

        % Value changed function: CalibrateFSRsensorButton
        function CalibrateFSRsensorButtonValueChanged(app, event)
            value = app.CalibrateFSRsensorButton.Value;
            
            weights = [0 20 50 70 100 120 150 170 200 220 250]; 
            m1 = zeros(length(weights), 1)'; 
            
            for i = 1:length(weights)
                mbox = msgbox(['Place ' num2str(weights(i)) ' grams on the FSR']); uiwait(mbox); 
                
                write(app.arduinoObj,"F","char"); 

                data = 'b'; 
                j = 1; 
                while data ~= 'a'
                    data = read(app.arduinoObj,  1, "char"); 
                    if (data ~= 'a')
                        str(j) = data; 
                        j = j+1; 
                    end
                end
                m1(i) =str2double(str); 
               
            end 
            m = m1; 
            app.P1 = polyfit( m, weights, 2); 
            app.calIndicator = true; 
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == '5'
                app.UIAxes.YLim = [0 5];

            else 
                app.UIAxes.YLim = [0 250];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BaudRateEditFieldLabel = uilabel(app.LeftPanel);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BaudRateEditField.Value = 9600;
            app.ComPortEditFieldLabel = uilabel(app.LeftPanel);
            app.ComPortEditFieldLabel.Text = 'Com Port';
            app.ComPortEditField = uieditfield(app.LeftPanel, 'text');
            app.ComPortEditField.Value = 'COM4';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.LeftPanel);
            app.AK1SliderLabel = uilabel(app.LeftPanel);
            app.AK1SliderLabel.Text = 'AK1';
            app.AK1Slider = uislider(app.LeftPanel);
            app.AK1Slider.ValueChangedFcn = createCallbackFcn(app, @AK1SliderValueChanged, true);
            app.AK2SliderLabel = uilabel(app.LeftPanel);
            app.AK2SliderLabel.Text = 'AK2';
            app.AK2Slider = uislider(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'FSR sensor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'y')
            app.samplefromFSRsensorButton = uibutton(app.RightPanel, 'push');
            app.samplefromFSRsensorButton.ButtonPushedFcn = createCallbackFcn(app, @samplefromFSRsensorButtonPushed, true);
            app.samplefromFSRsensorButton.Text = 'sample from FSR sensor';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Samplefrequencys1EditFieldLabel = uilabel(app.RightPanel);
            app.Samplefrequencys1EditFieldLabel.Text = 'Sample frequency [s^-1]';
            app.Samplefrequencys1EditField = uieditfield(app.RightPanel, 'numeric');
            app.Samplefrequencys1EditField.Value = 10;
            app.OBSSamplefrequencyupdateitsvaluewhenpressingstopLabel = uilabel(app.RightPanel);
            app.OBSSamplefrequencyupdateitsvaluewhenpressingstopLabel.Text = 'OBS. Sample frequency update its value, when pressing ''stop''';
            app.CalibrateFSRsensorButton = uibutton(app.RightPanel, 'state');
            app.CalibrateFSRsensorButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateFSRsensorButtonValueChanged, true);
            app.CalibrateFSRsensorButton.Text = 'Calibrate FSR sensor';
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.Items = {'5', '250'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabEapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end